classdef app1_converted2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RightPanel         matlab.ui.container.Panel
        HzLabel            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        LeftPanel          matlab.ui.container.Panel
        TextArea           matlab.ui.control.TextArea
        UploadSoundButton  matlab.ui.control.Button
        SelectSwitch       matlab.ui.control.Switch
        SelectSwitchLabel  matlab.ui.control.Label
        PlotgraphButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        music % Description
        maxXValue % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadSoundButton
        function UploadSoundButtonPushed(app, event)
[filename ,path]=uigetfile('*.mp3');
          app.music = [path filename];
          app.TextArea.Value=filename;
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Callback function: PlotgraphButton, UIAxes
        function PlotgraphButtonPushed(app, event)
          
          [X, Fs] = audioread(app.music);
          X = X(:,1);
          N = numel(X);
          T  = 1/Fs;      % [s] sampling period       
          deltaF = Fs/N;  % [1/min]) frequency intervalue of discrete signal
            
          % compute the fast fourier transform
          Y = fft(X);
          % manually shifting the FFT
          Y = abs(Y/N);
          Amp = [Y(ceil(end/2)+1:end)' Y(1) Y(2:ceil(end/2))']';
          if (mod(N,2) == 0)
          sampleIndex = -N/2:1:N/2-1; %raw index for FFT plot
          else
          sampleIndex = -(N-1)/2:1:(N-1)/2; %raw index for FFT plot
          end
          AmpSquared = Amp.^2;
          xIndex = find(AmpSquared == max(AmpSquared), 1, 'first');
          maxXValue = abs(deltaF*sampleIndex(xIndex));
          a =  num2str(maxXValue,'%.2f')
          if  (247 < maxXValue) && (maxXValue <277)
            app.Label.Text = a
          else
            app.Label.Text = 'F' 
          end
          plot(app.UIAxes,deltaF*sampleIndex, AmpSquared);
          axis(app.UIAxes,[0 2000 inf inf]);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.PlotgraphButton = uibutton(app.LeftPanel, 'push');
            app.PlotgraphButton.ButtonPushedFcn = createCallbackFcn(app, @PlotgraphButtonPushed, true);
            app.PlotgraphButton.Text = 'Plot graph';
            app.SelectSwitchLabel = uilabel(app.LeftPanel);
            app.SelectSwitchLabel.Text = 'Select';
            app.SelectSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SelectSwitch.Items = {'mic', 'file'};
            app.SelectSwitch.Value = 'mic';
            app.UploadSoundButton = uibutton(app.LeftPanel, 'push');
            app.UploadSoundButton.ButtonPushedFcn = createCallbackFcn(app, @UploadSoundButtonPushed, true);
            app.UploadSoundButton.Text = 'Upload Sound';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Power of FFT of Wave (Audible Range = 20-20\times10^4 Hz)')
            xlabel(app.UIAxes, 'Frequency [Hz]')
            ylabel(app.UIAxes, '|FFT(Wave)|^2')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @PlotgraphButtonPushed, true);
            app.Label = uilabel(app.RightPanel);
            app.HzLabel = uilabel(app.RightPanel);
            app.HzLabel.Text = 'Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end